classdef path_create_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        ExportStatusLabel    matlab.ui.control.Label
        ExportButton         matlab.ui.control.Button
        ValidateStatusLabel  matlab.ui.control.Label
        ValidateButton       matlab.ui.control.Button
        TransformPanel       matlab.ui.container.Panel
        TabGroup2            matlab.ui.container.TabGroup
        TranslateTab         matlab.ui.container.Tab
        ScaleTab             matlab.ui.container.Tab
        PivotTab             matlab.ui.container.Tab
        LengthenTab          matlab.ui.container.Tab
        CreatePanel          matlab.ui.container.Panel
        TabGroup             matlab.ui.container.TabGroup
        PointTab             matlab.ui.container.Tab
        SetPoint             matlab.ui.control.Button
        PointYEditField      matlab.ui.control.NumericEditField
        YEditFieldLabel      matlab.ui.control.Label
        PointXEditField      matlab.ui.control.NumericEditField
        XEditFieldLabel      matlab.ui.control.Label
        CreatePoint          matlab.ui.control.Button
        LineSegTab           matlab.ui.container.Tab
        SetLS                matlab.ui.control.Button
        LSX2EditField        matlab.ui.control.NumericEditField
        X2EditFieldLabel     matlab.ui.control.Label
        LSY2EditField        matlab.ui.control.NumericEditField
        Y2EditFieldLabel     matlab.ui.control.Label
        LSY1EditField        matlab.ui.control.NumericEditField
        Y1EditFieldLabel     matlab.ui.control.Label
        LSX1EditField        matlab.ui.control.NumericEditField
        X1EditFieldLabel     matlab.ui.control.Label
        CreateLS             matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Frame 
        Selected % for the graphical part
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function update_point(app, plotp)
            plotp.UserData.Pos = app.UIAxes.CurrentPoint(1, 1:2);

            app.TabGroup.SelectedTab = app.PointTab;
            app.PointXEditField.Value = plotp.XData(1);
            app.PointYEditField.Value = plotp.YData(1);
        end
        
        
        function redraw_ls_frompoint(app, point, lineplot, newpoint)
            % if linesegment is dragged by the point
            % update endp position, redraw line
            % is the linesegment a draggable or a line?

            ax = app.UIAxes;
%             newpoint = ax.CurrentPoint(1,1:2);
            endp = point.UserData;
            endp.setPos(newpoint(1), newpoint(2));

            oldX = lineplot.XData;
            oldY = lineplot.YData;

            if isequal(point.UserData, lineplot.UserData.P1)
                % p1 was dragged

                lineplot.XData = [endp.Pos(1) oldX(2)];
                lineplot.YData = [endp.Pos(2) oldY(2)];
            else 
                % p2 was dragged

                lineplot.XData = [oldX(1) endp.Pos(1)];
                lineplot.YData = [oldY(1) endp.Pos(2)];
            end

            % display in tabgroup
            app.TabGroup.SelectedTab = app.LineSegTab;
            app.LSX1EditField.Value = lineplot.XData(1);
            app.LSY1EditField.Value = lineplot.YData(1);
            app.LSX2EditField.Value = lineplot.XData(2);
            app.LSY2EditField.Value = lineplot.YData(2);

        end
        
        function redraw_ls_fromline(app, lineplot, p1, p2)
            % if linesegment is dragged by the line
            % update both endp positions
            % draggable objects can set_position
            
            newX = lineplot.XData;
            newY = lineplot.YData;    

            p1.XData = newX(1);
            p1.YData = newY(1);
            p2.XData = newX(2);
            p2.YData = newY(2);    

            line = lineplot.UserData;
            line.P1.setPos(newX(1), newY(1));
            line.P2.setPos(newX(2), newY(2));

            % display in tabgroup
            app.TabGroup.SelectedTab = app.LineSegTab;
            app.LSX1EditField.Value = lineplot.XData(1);
            app.LSY1EditField.Value = lineplot.YData(1);
            app.LSX2EditField.Value = lineplot.XData(2);
            app.LSY2EditField.Value = lineplot.YData(2);

        end
        
        
        function on_click(app, g)

            ax = app.UIAxes;

             % find all children, non-hgtransform, in figure
            allchildren = findobj(ax, '-depth', 2, ...
                '-not', 'Type','hgtransform', '-not', 'Type', 'axes');
            
            % if selected is part of transformed group. store in
            % axes.UserData
            transformed = [];
            if isequal(g.Parent.Type, "hgtransform")
                transformed = findobj(g.Parent,'-not','Type','hgtransform');
                ax.UserData = g.Parent;
            else
                % "selected" is a single plot if it's not connected to any
                % transform groups, but if it is, the "selected" object is
                % the whole group
                ax.UserData = g;
            end


            % set selected as red, all other objects in uiaxes blue
            for i = 1:length(allchildren)
                % if part of hgtransform group
                if ismember(allchildren(i), transformed)
                    allchildren(i).Color = 'r';
                % if just by itself (point)
                elseif isequal(allchildren(i), g)
                    allchildren(i).Color = 'r';
                else
                    allchildren(i).Color = 'b';
                end
            end
            

            % display in the tab groups
            if isa(g.UserData, 'Point')
                app.TabGroup.SelectedTab = app.PointTab;
                app.PointXEditField.Value = g.XData(1);
                app.PointYEditField.Value = g.YData(1);
            elseif isa(g.UserData, 'LineSegment')
                app.TabGroup.SelectedTab = app.LineSegTab;
                app.LSX1EditField.Value = g.XData(1);
                app.LSY1EditField.Value = g.YData(1);
                app.LSX2EditField.Value = g.XData(2);
                app.LSY2EditField.Value = g.YData(2);
            elseif isa(g.UserData, 'Endpoint')
                if isa(g.UserData.Segment, 'LineSegment')
                    app.TabGroup.SelectedTab = app.LineSegTab;
                    ls = g.UserData.Segment;
                    app.LSX1EditField.Value = ls.P1.Pos(1);
                    app.LSY1EditField.Value = ls.P1.Pos(2);
                    app.LSX2EditField.Value = ls.P2.Pos(1);
                    app.LSY2EditField.Value = ls.P2.Pos(2);
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Frame = Frame;
            
            ax = app.UIAxes();
            axis(ax, "equal");
            set(ax, "DataAspectRatio", [1 1 1], "Xlim", [-2 2], "YLim", [-2 2]);
            hold(ax, "on")
        end

        % Button pushed function: CreatePoint
        function CreatePointPushed(app, event)
            
            x = app.PointXEditField.Value;
            y = app.PointYEditField.Value;
            p = Point(app.Frame, x, y);

            plotp = plot(app.UIAxes, x, y);
            plotp.UserData = p;

            % *** on_click
            draggable(plotp, @(g)update_point(app, plotp),...
                @(g)on_click(app,plotp));

            % for visible vertices
            set(plotp, "Marker", "o", "MarkerSize", 10, "Color", "b", ...
                "MarkerFaceColor", "b", 'LineWidth', 2);
        end

        % Button pushed function: CreateLS
        function CreateLSButtonPushed(app, event)
            
            ax = app.UIAxes;
            x1 = app.LSX1EditField.Value;
            y1 = app.LSY1EditField.Value;
            x2 = app.LSX2EditField.Value;
            y2 = app.LSY2EditField.Value;
            lineseg = LineSegment(app.Frame, x1, y1, x2, y2);

            hg = hgtransform('Parent',ax);

            % make line
            lineplot = line(ax, [x1 x2], [y1 y2], 'Parent', hg);
            % store linesegment backend in line plot
            lineplot.UserData = lineseg;
            set(lineplot, "Color", "b", "LineWidth", 2);
            
            % make vertices
            p1 = plot(ax, x1, y1, 'Parent', hg);
            p2 = plot(ax, x2, y2, 'Parent', hg);
            % store backend in each line plot
            p1.UserData = lineseg.P1;
            p2.UserData = lineseg.P2;
            % make vertices visible
            points = [p1 p2];
            set(points, "Marker", "o", "MarkerSize", 10, "Color", "b", ...
                "MarkerFaceColor", "b", 'LineWidth', 2);

            draggable(p1, @(g)redraw_ls_frompoint(app, p1, lineplot, ...
                ax.CurrentPoint(1,1:2)), @(g)on_click(app,p1));
            draggable(p2, @(g)redraw_ls_frompoint(app, p2, lineplot,...
                ax.CurrentPoint(1,1:2)), @(g)on_click(app,p2));
            draggable(lineplot, @(g)redraw_ls_fromline(app, lineplot, p1, p2),...
                @(g)on_click(app,lineplot));

        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            try 
%                 for i = 1 : length(app.Frame.SegmentList)
%                     app.Frame.SegmentList(i).P1.Pos
%                     isempty(app.Frame.SegmentList(i).P1.Connected)
%                     app.Frame.SegmentList(i).P2.Pos
%                     isempty(app.Frame.SegmentList(i).P2.Connected)
%                 end
                app.Frame.validate;
                app.ValidateStatusLabel.Text = 'Success!';
            catch 
                warning("Path validation was unsuccessful")
                app.ValidateStatusLabel.Text = 'Path validation was unsuccessful';
            end
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            try
                app.Frame.export;
                app.ExportStatusLabel.Text = 'Success!';
            catch
                warning("Export failed. Please first complete validated path.");
                app.ExportStatusLabel.Text = 'Export was unsuccessful';
            end
        end

        % Button pushed function: SetLS
        function SetLSPushed(app, event)
            x1 = app.LSX1EditField.Value;
            y1 = app.LSY1EditField.Value;
            x2 = app.LSX2EditField.Value;
            y2 = app.LSY2EditField.Value;

            selected = app.UIAxes.UserData;
                
            if isequal(selected.Type, "hgtransform")
                % find the line objects in hgtransform group
                ls = findobj(selected, 'Type', 'line', 'Marker', 'none');
                if ~isempty(ls)
                    % check if line objects is a linesegment
                    if isa(ls(1).UserData, 'LineSegment')
                        ls(1).UserData.setPos(x1, y1, x2, y2);

                        ls(1).XData = [x1 x2];
                        ls(1).YData = [y1 y2];

                        vertices = findobj(selected, 'Type', 'line', 'Marker', 'o');
                        if isequal(vertices(1).UserData, ls(1).UserData.P1)
                            % first in vertices array is P1
                            redraw_ls_fromline(app, ls(1), vertices(1), vertices(2));
                        else
                            % first in vertices array is P2
                            redraw_ls_fromline(app, ls(1), vertices(2), vertices(1));
                        end
                        return
                    end
                end
            end

            warning("Please select a line segment to set");
        end

        % Button pushed function: SetPoint
        function SetPointButtonPushed(app, event)
            x = app.PointXEditField.Value;
            y = app.PointYEditField.Value;

            selected = app.UIAxes.UserData;

            if isa(selected.UserData, "Point")
                selected.XData = x;
                selected.YData = y;
    
                selected.UserData.Pos = [x y];
            else
                warning("Please select a point to set")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CreatePanel = uipanel(app.UIFigure);
            app.CreatePanel.Title = 'Create';
            app.TabGroup = uitabgroup(app.CreatePanel);
            app.PointTab = uitab(app.TabGroup);
            app.PointTab.Title = 'Point';
            app.CreatePoint = uibutton(app.PointTab, 'push');
            app.CreatePoint.ButtonPushedFcn = createCallbackFcn(app, @CreatePointPushed, true);
            app.CreatePoint.Text = 'Create';
            app.XEditFieldLabel = uilabel(app.PointTab);
            app.XEditFieldLabel.Text = 'X';
            app.PointXEditField = uieditfield(app.PointTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.PointTab);
            app.YEditFieldLabel.Text = 'Y';
            app.PointYEditField = uieditfield(app.PointTab, 'numeric');
            app.SetPoint = uibutton(app.PointTab, 'push');
            app.SetPoint.ButtonPushedFcn = createCallbackFcn(app, @SetPointButtonPushed, true);
            app.SetPoint.Text = 'Set';
            app.LineSegTab = uitab(app.TabGroup);
            app.LineSegTab.Title = 'LineSeg';
            app.CreateLS = uibutton(app.LineSegTab, 'push');
            app.CreateLS.ButtonPushedFcn = createCallbackFcn(app, @CreateLSButtonPushed, true);
            app.CreateLS.Text = 'Create';
            app.X1EditFieldLabel = uilabel(app.LineSegTab);
            app.X1EditFieldLabel.Text = 'X1';
            app.LSX1EditField = uieditfield(app.LineSegTab, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.LineSegTab);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.LSY1EditField = uieditfield(app.LineSegTab, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.LineSegTab);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.LSY2EditField = uieditfield(app.LineSegTab, 'numeric');
            app.X2EditFieldLabel = uilabel(app.LineSegTab);
            app.X2EditFieldLabel.Text = 'X2';
            app.LSX2EditField = uieditfield(app.LineSegTab, 'numeric');
            app.LSX2EditField.Value = 1;
            app.SetLS = uibutton(app.LineSegTab, 'push');
            app.SetLS.ButtonPushedFcn = createCallbackFcn(app, @SetLSPushed, true);
            app.SetLS.Text = 'Set';
            app.TransformPanel = uipanel(app.UIFigure);
            app.TransformPanel.Title = 'Transform';
            app.TabGroup2 = uitabgroup(app.TransformPanel);
            app.TranslateTab = uitab(app.TabGroup2);
            app.TranslateTab.Title = 'Translate';
            app.ScaleTab = uitab(app.TabGroup2);
            app.ScaleTab.Title = 'Scale';
            app.PivotTab = uitab(app.TabGroup2);
            app.PivotTab.Title = 'Pivot';
            app.LengthenTab = uitab(app.TabGroup2);
            app.LengthenTab.Title = 'Lengthen';
            app.Panel = uipanel(app.UIFigure);
            app.ValidateButton = uibutton(app.Panel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.ValidateStatusLabel = uilabel(app.Panel);
            app.ValidateStatusLabel.Text = 'Status';
            app.ExportButton = uibutton(app.Panel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ExportStatusLabel = uilabel(app.Panel);
            app.ExportStatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = path_create_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end